classdef fas_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup2                   matlab.ui.container.TabGroup
        InitialConcentrationsuMTab  matlab.ui.container.Tab
        AuxillaryComponentsPanel    matlab.ui.container.Panel
        EnzDrop4                    matlab.ui.control.DropDown
        EnzDrop3                    matlab.ui.control.DropDown
        EnzDrop2                    matlab.ui.control.DropDown
        EnzDrop1                    matlab.ui.control.DropDown
        Aux6EditField               matlab.ui.control.NumericEditField
        Aux6EditFieldLabel          matlab.ui.control.Label
        Aux5EditField               matlab.ui.control.NumericEditField
        Aux5EditFieldLabel          matlab.ui.control.Label
        PrimaryReset                matlab.ui.control.Button
        Aux4EditField               matlab.ui.control.NumericEditField
        Aux4EditFieldLabel          matlab.ui.control.Label
        Aux3EditField               matlab.ui.control.NumericEditField
        Aux3EditFieldLabel          matlab.ui.control.Label
        Aux2EditField               matlab.ui.control.NumericEditField
        Aux2EditFieldLabel          matlab.ui.control.Label
        Aux1EditField               matlab.ui.control.NumericEditField
        Aux1EditFieldLabel          matlab.ui.control.Label
        ATPEditField                matlab.ui.control.NumericEditField
        ATPEditFieldLabel           matlab.ui.control.Label
        TEDropDown                  matlab.ui.control.DropDown
        TEDropDownLabel             matlab.ui.control.Label
        TEEditField                 matlab.ui.control.NumericEditField
        TEEditFieldLabel            matlab.ui.control.Label
        BaseFASPanel                matlab.ui.container.Panel
        SecondaryReset              matlab.ui.control.Button
        NADHEditField               matlab.ui.control.NumericEditField
        NADHEditField_2Label        matlab.ui.control.Label
        NADPHEditField              matlab.ui.control.NumericEditField
        NADPHEditField_2Label       matlab.ui.control.Label
        holoACPEditField            matlab.ui.control.NumericEditField
        holoACPEditField_2Label     matlab.ui.control.Label
        MalonylCoAEditField         matlab.ui.control.NumericEditField
        MalonylCoAEditField_2Label  matlab.ui.control.Label
        AcetylCoAEditField          matlab.ui.control.NumericEditField
        AcetylCoAEditField_2Label   matlab.ui.control.Label
        FabBEditField               matlab.ui.control.NumericEditField
        FabBEditField_6Label        matlab.ui.control.Label
        FabAEditField               matlab.ui.control.NumericEditField
        FabAEditField_2Label        matlab.ui.control.Label
        FabFEditField               matlab.ui.control.NumericEditField
        FabFEditFieldLabel          matlab.ui.control.Label
        FabIEditField               matlab.ui.control.NumericEditField
        FabIEditField_2Label        matlab.ui.control.Label
        FabZEditField               matlab.ui.control.NumericEditField
        FabZEditField_2Label        matlab.ui.control.Label
        FabHEditField               matlab.ui.control.NumericEditField
        FabHEditFieldLabel          matlab.ui.control.Label
        FabGEditField               matlab.ui.control.NumericEditField
        FabGEditField_2Label        matlab.ui.control.Label
        FabDEditField               matlab.ui.control.NumericEditField
        FabDEditField_2Label        matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        RunModelTab                 matlab.ui.container.Tab
        PlotTypeDropDown            matlab.ui.control.DropDown
        PlotTypeLabel               matlab.ui.control.Label
        RUNButton                   matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        Setup                       matlab.ui.container.Panel
        savedtext                   matlab.ui.control.Label
        SAVEButton                  matlab.ui.control.Button
        matLabel                    matlab.ui.control.Label
        SaveRunAsEditField          matlab.ui.control.EditField
        SaveRunAsEditFieldLabel     matlab.ui.control.Label
        TimeSpanEditField           matlab.ui.control.NumericEditField
        TimeSpansEditFieldLabel     matlab.ui.control.Label
        ModelScopeDropDown          matlab.ui.control.DropDown
        ModelScopeLabel             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % navigate to root FAS directory       
            try
                error_code = check_dir();
            catch
                error('Function "check_dir" is missing from FAS_app directory. Please restore this function.')
            end
            
            if error_code
                error('Please navigate workspace to "FAS_app" directory')
            else
                addpath(cd)
            end
            
            % test for presence of helper functions
            try
                if ismac
                    is_present = dir('helper_functions/');
                    is_present(1);
                    addpath('helper_functions/')
                elseif ispc
                    is_present = dir('helper_functions\');
                    is_present(1);
                    addpath('helper_functions\')
                end
            catch
                error('Directory "helper_functions" not found. Please restore directory.')
            end
            
            % test for presence of results folder and initialize if not
%             try
%                 if ismac
%                     is_present = dir('FAS_results/');
%                     is_present (1);
%                 elseif ispc
%                     is_present = dir('FAS_results/');
%                     is_present (1);
%                 end
%             catch
%                 if ismac
%                     mkdir('FAS_results/')
%                 elseif ispc
%                     mkdir('FAS_results/')
%         end

            % set run button color
            app.RUNButton.BackgroundColor = [0.2 0.8 0];

            for ii = 1:6
                auxstr = 'app.Aux';
                eval([auxstr num2str(ii) 'EditFieldLabel' '.Enable = false;']);
                eval([auxstr num2str(ii) 'EditField' '.Value = 0;']);
                eval([auxstr num2str(ii) 'EditField' '.Enable = false;']);
                eval([auxstr num2str(ii) 'EditField.Visible = "off";']);
                eval([auxstr num2str(ii) 'EditFieldLabel.Visible = "off";']);
            end
            app.ATPEditField.Enable = false;
            app.ATPEditFieldLabel.Enable = false;
            app.ATPEditField.Value = 0;      
            app.ATPEditField.Visible = 'off';
            app.ATPEditFieldLabel.Visible = 'off';
            app.EnzDrop1.Visible = 'off';
            app.EnzDrop2.Visible = 'off';
            app.EnzDrop3.Visible = 'off';
            app.EnzDrop4.Visible = 'off';


            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: ModelScopeDropDown
        function ModelScopeDropDownValueChanged(app, event)
            % This tells the GUI which extension we're trying to model 
            % ex) fatty acids, MEKs, Fatty Alcohols, etc.
            model_scope = app.ModelScopeDropDown.Value;

            % all enzyme/cofactor labels unique to each model extension are
            % hard coded in this case structure
            app.TEDropDown.Enable = true;
            app.TEDropDownLabel.Enable = true;
            app.TEEditField.Enable = true;
            app.TEEditFieldLabel.Enable = true;
            app.TEEditField.Visible = 'on';
            app.TEEditFieldLabel.Visible = 'on';
            app.TEDropDown.Visible = 'on';
            app.TEDropDownLabel.Visible = 'on';

            if ~strcmp(model_scope,'Fatty Acids') 
                app.ATPEditField.Enable = true;
                app.ATPEditFieldLabel.Enable = true;
                app.ATPEditField.Value = 1000;
                app.ATPEditField.Visible = 'on';
                app.ATPEditFieldLabel.Visible = 'on';
            else 
                app.ATPEditField.Enable = false;
                app.ATPEditFieldLabel.Enable = false;
                app.ATPEditField.Value = 0;
                app.ATPEditField.Visible = 'off';
                app.ATPEditFieldLabel.Visible = 'off';
            end
            for ii = 1:6
                auxstr = 'app.Aux';
                eval([auxstr num2str(ii) 'EditFieldLabel' '.Text = "Aux'  num2str(ii) '";']);
                eval([auxstr num2str(ii) 'EditField' '.Enable = false;']);
                eval([auxstr num2str(ii) 'EditField' '.Value = 0;']);
                eval([auxstr num2str(ii) 'EditField.Visible = "off";']);
                eval([auxstr num2str(ii) 'EditFieldLabel.Visible = "off";']);
            end 

            app.EnzDrop1.Visible = 'off';
            app.EnzDrop2.Visible = 'off';
            app.EnzDrop3.Visible = 'off';
            app.EnzDrop4.Visible = 'off';
            app.FabDEditField.Value = 1;
            app.FabHEditField.Value = 1;
            app.FabGEditField.Value = 1;
            app.FabIEditField.Value = 1;
            app.AcetylCoAEditField.Value = 500;
            app.MalonylCoAEditField.Value = 500;
            app.NADPHEditField.Value = 1000;
            app.NADHEditField.Value = 1000;
            app.holoACPEditField.Value = 10;
            app.Aux3EditField.Position = [222,129,68,22];
            app.Aux3EditFieldLabel.Position = [175,130,33,22];
            switch model_scope
                case 'Fatty Acids'
                    aux_labels = {};
                    app.FabAEditField.Value = 1;
                    app.FabZEditField.Value = 1;
                    app.FabFEditField.Value = 1;
                    app.FabBEditField.Value = 1;
                    app.TEEditField.Value = 10;
                    app.TEDropDown.Value = 'TesA';
                    app.PlotTypeDropDown.Value = 'Product Profile';
                    app.PlotTypeDropDown.Items = {'Product Profile', 'Acyl-ACPs', 'Fatty Acids'};
                    
                case 'Alcohols'
                    aux_labels = {'CAR','AHR'};
                    app.FabAEditField.Value = 0.0394;
                    app.FabZEditField.Value = 2.5902;
                    app.FabFEditField.Value = 0.4976;
                    app.FabBEditField.Value = 1.5288;
                    app.TEEditField.Value = 10;
                    app.Aux1EditField.Value = 10;
                    app.Aux2EditField.Value = 10;
                    app.Aux1EditField.Visible = 'on';
                    app.Aux2EditField.Visible = 'on';
                    app.Aux1EditFieldLabel.Visible = 'on';
                    app.Aux2EditFieldLabel.Visible = 'on';
                    app.Aux3EditField.Visible = 'off';
                    app.Aux4EditField.Visible = 'off';
                    app.Aux3EditFieldLabel.Visible = 'off';
                    app.Aux4EditFieldLabel.Visible = 'off';
                    app.Aux5EditField.Visible = 'off';
                    app.Aux6EditField.Visible = 'off';
                    app.Aux5EditFieldLabel.Visible = 'off';
                    app.Aux6EditFieldLabel.Visible = 'off';
                    app.TEDropDown.Value = 'TesA';
                    app.PlotTypeDropDown.Value = 'Product Profile';
                    app.PlotTypeDropDown.Items = {'Product Profile', 'Acyl-ACPs', 'Fatty Acids', 'Fatty Aldehydes', 'Fatty Alcohols'};  
                case 'Alcohols - ACR1'
                    aux_labels = {'FadD','ACR1', 'AHR'};
                    app.FabAEditField.Value = 0.0394;
                    app.FabZEditField.Value = 2.5902;
                    app.FabFEditField.Value = 0.4976;
                    app.FabBEditField.Value = 1.5288;
                    app.TEEditField.Value = 0.1840;
                    app.Aux3EditField.Position = [224,162,68,22];
                    app.Aux3EditFieldLabel.Position = [177,163,33,22];
                    app.Aux1EditField.Value = 10;
                    app.Aux2EditField.Value = 10;
                    app.Aux3EditField.Value = 10;
                    app.Aux1EditField.Visible = 'on';
                    app.Aux2EditField.Visible = 'on';
                    app.Aux3EditField.Visible = 'on';
                    app.Aux1EditFieldLabel.Visible = 'on';
                    app.Aux2EditFieldLabel.Visible = 'on';
                    app.Aux3EditFieldLabel.Visible = 'on';
                    app.Aux4EditField.Visible = 'off';
                    app.Aux4EditFieldLabel.Visible = 'off';
                    app.Aux5EditField.Visible = 'off';
                    app.Aux6EditField.Visible = 'off';
                    app.Aux5EditFieldLabel.Visible = 'off';
                    app.Aux6EditFieldLabel.Visible = 'off';
                    app.TEDropDown.Value = 'TesA';
                    app.PlotTypeDropDown.Value = 'Product Profile';
                    app.PlotTypeDropDown.Items = {'Product Profile', 'Acyl-ACPs','Fatty Acids', 'Acyl-CoAs','Fatty Aldehydes', 'Fatty Alcohols'};     
                case 'Alcohols - ACR2'
                    aux_labels = {'FadD','ACR2'};
                    app.FabAEditField.Value = 0.0394;
                    app.FabZEditField.Value = 2.5902;
                    app.FabFEditField.Value = 0.4976;
                    app.FabBEditField.Value = 1.5288;
                    app.TEEditField.Value = 0.5530;
                    app.Aux1EditField.Value = 10;
                    app.Aux2EditField.Value = 0.0130;
                    app.Aux1EditField.Visible = 'on';
                    app.Aux2EditField.Visible = 'on';
                    app.Aux1EditFieldLabel.Visible = 'on';
                    app.Aux2EditFieldLabel.Visible = 'on';
                    app.Aux3EditField.Visible = 'off';
                    app.Aux4EditField.Visible = 'off';
                    app.Aux3EditFieldLabel.Visible = 'off';
                    app.Aux4EditFieldLabel.Visible = 'off';
                    app.Aux5EditField.Visible = 'off';
                    app.Aux6EditField.Visible = 'off';
                    app.Aux5EditFieldLabel.Visible = 'off';
                    app.Aux6EditFieldLabel.Visible = 'off';
                    app.TEDropDown.Value = 'TesA';
                    app.PlotTypeDropDown.Value = 'Product Profile';
                    app.PlotTypeDropDown.Items = {'Product Profile', 'Acyl-ACPs', 'Fatty Acids', 'Acyl-CoAs', 'Fatty Alcohols'};   
                case 'Alcohols - ATR'
                    aux_labels = {'ATR'};
                    app.FabAEditField.Value = 0.0394;
                    app.FabZEditField.Value = 2.5902;
                    app.FabFEditField.Value = 0.4976;
                    app.FabBEditField.Value = 1.5288;
                    app.TEDropDown.Enable = false;
                    app.TEEditField.Enable = false;
                    app.TEEditFieldLabel.Enable = false;
                    app.TEEditField.Value = 0;
                    app.TEEditField.Visible = 'off';
                    app.TEEditFieldLabel.Visible = 'off';
                    app.TEDropDown.Visible = 'off';
                    app.TEDropDownLabel.Visible = 'off';
                    app.Aux1EditField.Value = 10;
                    app.Aux1EditField.Visible = 'on';
                    app.Aux2EditField.Visible = 'off';
                    app.Aux1EditFieldLabel.Visible = 'on';
                    app.Aux2EditFieldLabel.Visible = 'off';
                    app.Aux3EditField.Visible = 'off';
                    app.Aux4EditField.Visible = 'off';
                    app.Aux3EditFieldLabel.Visible = 'off';
                    app.Aux4EditFieldLabel.Visible = 'off';
                    app.Aux5EditField.Visible = 'off';
                    app.Aux6EditField.Visible = 'off';
                    app.Aux5EditFieldLabel.Visible = 'off';
                    app.Aux6EditFieldLabel.Visible = 'off';
                    app.PlotTypeDropDown.Value = 'Product Profile';
                    app.PlotTypeDropDown.Items = {'Product Profile', 'Acyl-ACPs', 'Fatty Alcohols'};     
                case 'Alkanes'
                    aux_labels = {'AAR','ADO'};
                    app.FabAEditField.Value = 0.0394;
                    app.FabZEditField.Value = 2.5902;
                    app.FabFEditField.Value = 0.4976;
                    app.FabBEditField.Value = 1.5288;
                    app.TEDropDown.Enable = false;
                    app.TEEditField.Enable = false;
                    app.TEEditFieldLabel.Enable = false;
                    app.TEEditField.Value = 0;
                    app.TEEditField.Visible = 'off';
                    app.TEEditFieldLabel.Visible = 'off';
                    app.TEDropDown.Visible = 'off';
                    app.TEDropDownLabel.Visible = 'off';
                    app.Aux1EditField.Value = 94.56;
                    app.Aux2EditField.Value = 10;
                    app.Aux1EditField.Visible = 'on';
                    app.Aux2EditField.Visible = 'on';
                    app.Aux1EditFieldLabel.Visible = 'on';
                    app.Aux2EditFieldLabel.Visible = 'on';
                    app.Aux3EditField.Visible = 'off';
                    app.Aux4EditField.Visible = 'off';
                    app.Aux3EditFieldLabel.Visible = 'off';
                    app.Aux4EditFieldLabel.Visible = 'off';
                    app.Aux5EditField.Visible = 'off';
                    app.Aux6EditField.Visible = 'off';
                    app.Aux5EditFieldLabel.Visible = 'off';
                    app.Aux6EditFieldLabel.Visible = 'off';
                    app.PlotTypeDropDown.Value = 'Product Profile';
                    app.PlotTypeDropDown.Items = {'Product Profile', 'Acyl-ACPs', 'Fatty Aldehydes', 'Alkanes'}; 
                case 'Methyl Ketones'
                    aux_labels = {'FAD','NAD','FadD','FadE','FadB','FadM'};
                    app.FabAEditField.Value = 0.0394;
                    app.FabZEditField.Value = 2.5902;
                    app.FabFEditField.Value = 0.4976;
                    app.FabBEditField.Value = 1.5288;
                    app.TEEditField.Value = 10;
                    app.TEDropDown.Value = 'BTE';
                    app.Aux1EditField.Value = 0;
                    app.Aux2EditField.Value = 2600;
                    app.Aux3EditField.Value = 10;
                    app.Aux4EditField.Value = 10;
                    app.Aux5EditField.Value = 10;
                    app.Aux6EditField.Value = 10;
                    app.EnzDrop1.Visible = 'on';
                    app.EnzDrop2.Visible = 'on';
                    app.EnzDrop3.Visible = 'on';
                    app.EnzDrop4.Visible = 'on';
                    app.EnzDrop1.Items = {'ecFadD','FadD6'};
                    app.EnzDrop2.Items = {'ecFadE','ACO'};
                    app.EnzDrop3.Items = {'ecFadB','PpFadB'};
                    app.EnzDrop4.Items = {'ecFadM','PsFadM'};
                    app.EnzDrop1.Value = 'ecFadD';
                    app.EnzDrop2.Value = 'ACO';
                    app.EnzDrop3.Value = 'PpFadB';
                    app.EnzDrop4.Value = 'PsFadM';
                    app.Aux1EditField.Visible = 'on';
                    app.Aux2EditField.Visible = 'on';
                    app.Aux1EditFieldLabel.Visible = 'on';
                    app.Aux2EditFieldLabel.Visible = 'on';
                    app.Aux3EditField.Visible = 'on';
                    app.Aux4EditField.Visible = 'on';
                    app.Aux3EditFieldLabel.Visible = 'on';
                    app.Aux4EditFieldLabel.Visible = 'on';
                    app.Aux5EditField.Visible = 'on';
                    app.Aux6EditField.Visible = 'on';
                    app.Aux5EditFieldLabel.Visible = 'on';
                    app.Aux6EditFieldLabel.Visible = 'on';
                    app.PlotTypeDropDown.Value = 'Product Profile';
                    app.PlotTypeDropDown.Items = {'Product Profile', 'Acyl-ACPs', 'Fatty Acids', 'Acyl-CoAs', 'Methyl Ketones'};      
                case 'Methyl Ketones - Thiolase'
                    aux_labels = {'FAD','NAD','FadD','Thio','FadM'};
                    app.FabAEditField.Value = 0.0394;
                    app.FabZEditField.Value = 2.5902;
                    app.FabFEditField.Value = 0.4976;
                    app.FabBEditField.Value = 1.5288;
                    app.TEEditField.Value = 10;
                    app.TEDropDown.Value = 'CpFatB';
                    app.Aux1EditField.Value = 170;
                    app.Aux2EditField.Value = 2600;
                    app.Aux3EditField.Value = 10;
                    app.Aux4EditField.Value = 10;
                    app.Aux5EditField.Value = 10;
                    app.EnzDrop1.Items = {'ecFadD','FadD6'};
                    app.EnzDrop2.Items = {'FadA','ReBktB'};
                    app.EnzDrop3.Items = {'ecFadM','PsFadM'};
                    app.EnzDrop1.Visible = 'on';
                    app.EnzDrop2.Visible = 'on';
                    app.EnzDrop3.Visible = 'on';
                    app.EnzDrop4.Visible = 'off';
                    app.EnzDrop1.Value = 'FadD6';
                    app.EnzDrop2.Value = 'ReBktB';
                    app.EnzDrop3.Value = 'PsFadM';
                    app.Aux1EditField.Visible = 'on';
                    app.Aux2EditField.Visible = 'on';
                    app.Aux1EditFieldLabel.Visible = 'on';
                    app.Aux2EditFieldLabel.Visible = 'on';
                    app.Aux3EditField.Visible = 'on';
                    app.Aux4EditField.Visible = 'on';
                    app.Aux3EditFieldLabel.Visible = 'on';
                    app.Aux4EditFieldLabel.Visible = 'on';
                    app.Aux5EditField.Visible = 'on';
                    app.Aux6EditField.Visible = 'off';
                    app.Aux5EditFieldLabel.Visible = 'on';
                    app.Aux6EditFieldLabel.Visible = 'off';
                    app.PlotTypeDropDown.Value = 'Product Profile';
                    app.PlotTypeDropDown.Items = {'Product Profile', 'Acyl-ACPs', 'Fatty Acids', 'Acyl-CoAs', 'Methyl Ketones'};     
                case 'FAMEs'
                    aux_labels = {'SAM','DmJHAMT'};
                    app.FabAEditField.Value = 0.0394;
                    app.FabZEditField.Value = 2.5902;
                    app.FabFEditField.Value = 0.4976;
                    app.FabBEditField.Value = 1.5288;
                    app.TEEditField.Value = 10;
                    app.TEDropDown.Value = 'BTE';
                    app.Aux1EditField.Value = 70.8;
                    app.Aux2EditField.Value = 10;
                    app.Aux1EditField.Visible = 'on';
                    app.Aux2EditField.Visible = 'on';
                    app.Aux1EditFieldLabel.Visible = 'on';
                    app.Aux2EditFieldLabel.Visible = 'on';
                    app.Aux3EditField.Visible = 'off';
                    app.Aux4EditField.Visible = 'off';
                    app.Aux3EditFieldLabel.Visible = 'off';
                    app.Aux4EditFieldLabel.Visible = 'off';
                    app.Aux5EditField.Visible = 'off';
                    app.Aux6EditField.Visible = 'off';
                    app.Aux5EditFieldLabel.Visible = 'off';
                    app.Aux6EditFieldLabel.Visible = 'off';
                    app.PlotTypeDropDown.Value = 'Product Profile';
                    app.PlotTypeDropDown.Items = {'Product Profile', 'Acyl-ACPs', 'Fatty Acids', 'FAMEs'};    
                case 'FAEEs'
                    aux_labels = {'FadD','atfA'};
                    app.FabAEditField.Value = 0.0394;
                    app.FabZEditField.Value = 2.5902;
                    app.FabFEditField.Value = 0.4976;
                    app.FabBEditField.Value = 1.5288;
                    app.TEEditField.Value = 2.0206;
                    app.Aux1EditField.Value = 10;
                    app.Aux2EditField.Value = 10;
                    app.Aux1EditField.Visible = 'on';
                    app.Aux2EditField.Visible = 'on';
                    app.Aux1EditFieldLabel.Visible = 'on';
                    app.Aux2EditFieldLabel.Visible = 'on';
                    app.Aux3EditField.Visible = 'off';
                    app.Aux4EditField.Visible = 'off';
                    app.Aux3EditFieldLabel.Visible = 'off';
                    app.Aux4EditFieldLabel.Visible = 'off';
                    app.Aux5EditField.Visible = 'off';
                    app.Aux6EditField.Visible = 'off';
                    app.Aux5EditFieldLabel.Visible = 'off';
                    app.Aux6EditFieldLabel.Visible = 'off';
                    app.TEDropDown.Value = 'TesA';
                    app.PlotTypeDropDown.Value = 'Product Profile';
                    app.PlotTypeDropDown.Items = {'Product Profile', 'Acyl-ACPs', 'Fatty Acids', 'Acyl-CoAs', 'FAEEs'};  
                otherwise
                    error('no match for "Model Scope"')
            end
    
            % use string evaluation to update variably named GUI components
            for ii = 1:numel(aux_labels)
                auxstr = 'app.Aux';
                eval([auxstr num2str(ii) 'EditField' '.Enable = true;']);
                eval([auxstr num2str(ii) 'EditFieldLabel.Enable = true;']);
                eval([auxstr num2str(ii) 'EditField' 'Label.Text = aux_labels(ii);']);
            end
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            % index in this list corresponds to position in the initial
            % conditions matrix for the model, must always be 1:1 match
            fas_enzymes = [0, ...
                app.FabDEditField.Value, ...
                app.FabHEditField.Value, ...
                app.FabGEditField.Value, ...
                app.FabZEditField.Value, ...
                app.FabIEditField.Value,...
                app.TEEditField.Value, ...
                app.FabFEditField.Value, ...
                app.FabAEditField.Value, ...
                app.FabBEditField.Value ...
                ];

            fas_components = [app.ATPEditField.Value, 0, ...
                app.AcetylCoAEditField.Value, ...
                app.holoACPEditField.Value, ...
                app.NADPHEditField.Value, ...
                app.NADHEditField.Value, ...
                0, app.MalonylCoAEditField.Value, ...
                0,0,0];


            model_scope = app.ModelScopeDropDown.Value;

            % collect auxiliary enzyme conc. from aux comp tab
            if strcmp(model_scope,'Fatty Acids')
                aux_enz = NaN;
            else
                aux_enz = NaN(1,6);
                for i = 1:6
                    auxstr = 'app.Aux';
                    eval(['aux_enz(i) =' auxstr num2str(i) 'EditField' '.Value;']);
                end
            end

            if strcmp(model_scope, 'Methyl Ketones')
                enz_identity = strings(1,4);
                enz_identity(1) = app.EnzDrop1.Value;
                enz_identity(2) = app.EnzDrop2.Value;
                enz_identity(3) = app.EnzDrop3.Value;
                enz_identity(4) = app.EnzDrop4.Value;
            elseif strcmp(model_scope, 'Methyl Ketones - Thiolase')
                enz_identity = strings(1,3);
                enz_identity(1) = app.EnzDrop1.Value;
                enz_identity(2) = app.EnzDrop2.Value;
                enz_identity(3) = app.EnzDrop3.Value;
            else
                enz_identity = NaN;
            end

            % combine user inputs
            user = struct;
            user.model_scope = model_scope;
            user.aux_enz = aux_enz;
            user.fas_enz = fas_enzymes;
            user.fas_com = fas_components;
            user.time = [0 app.TimeSpanEditField.Value];
            user.TE = app.TEDropDown.Value;
            user.enz_identity = enz_identity;

            % Define number of iterations being performed;
            % Current GUI only performs 1 simulation per run, but this
            % could be a future user input to easily simulate a range
            % of values for one variable.
            N = 1;

            % RUN
            app.RUNButton.BackgroundColor = [0 1 0.5];
            app.RUNButton.Text = '...';
            pause(0.5);
            for ii = 1:N
                results = model_sorter(user); % function that decides which model to use
            end
            app.RUNButton.Text = 'RUN';
            app.RUNButton.BackgroundColor = [0.2 0.8 0];

%             % SAVE simulation results
%             save('previous_results.mat','results_struct')

            % PLOT
            plotter(app,results);

            app.SAVEButton.Enable = true;

            % store previous run
%             app.SaveRunAsEditField.FontColor = [1 1 1];
%             app.SaveRunAsEditField.Value = 'PreviousRun';
%             SAVEButtonPushed(app,event)
%             app.SaveRunAsEditField.Value = '';
%             app.SaveRunAsEditField.FontColor = [0 0 0];


        end

        % Button pushed function: SecondaryReset
        function SecondaryResetPushed(app, event)
            % resets Base FAS initial conditions to original values
            model_scope = app.ModelScopeDropDown.Value;
            switch model_scope 
                case "Fatty Acids"
                    app.FabZEditField.Value = 1;
                    app.FabFEditField.Value = 1;
                    app.FabAEditField.Value = 1;
                    app.FabBEditField.Value = 1;
                otherwise 
                    app.FabAEditField.Value = 0.0394;
                    app.FabZEditField.Value = 2.5902;
                    app.FabFEditField.Value = 0.4976;
                    app.FabBEditField.Value = 1.5288;
            end 
                    app.FabDEditField.Value = 1;
                    app.FabHEditField.Value = 1;
                    app.FabGEditField.Value = 1;
                    app.FabIEditField.Value = 1;
                    app.AcetylCoAEditField.Value = 500;
                    app.MalonylCoAEditField.Value = 500;
                    app.NADPHEditField.Value = 1000;
                    app.NADHEditField.Value = 1000;
                    app.holoACPEditField.Value = 10;

            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Button pushed function: PrimaryReset
        function PrimaryResetButtonPushed(app, event)
            % resets knob values to originals for preset configuration
            model_scope = app.ModelScopeDropDown.Value;
            switch model_scope 
                case "Fatty Acids"
                    app.ATPEditField.Value = 0;
                    app.TEDropDown.Value = 'TesA';
                    app.TEDropDown.Value = 10;
                case "Alcohols"
                    app.TEEditField.Value = 10;
                    app.ATPEditField.Value = 1000;
                    app.TEDropDown.Value = 'TesA';
                    app.Aux1EditField.Value = 10;
                    app.Aux2EditField.Value = 10;
                case "Alcohols - ACR1"
                    app.TEEditField.Value = 0.1840;
                    app.ATPEditField.Value = 1000;
                    app.TEDropDown.Value = 'TesA';
                    app.Aux1EditField.Value = 10;
                    app.Aux2EditField.Value = 10;
                    app.Aux3EditField.Value = 10;
                case "Alcohols - ACR2"
                    app.TEEditField.Value = 0.5530;
                    app.ATPEditField.Value = 1000;
                    app.TEDropDown.Value = 'TesA';
                    app.Aux1EditField.Value = 10;
                    app.Aux2EditField.Value = 0.0130;
                case 'Alcohols - ATR'
                    app.TEEditField.Value = 0;
                    app.ATPEditField.Value = 1000;
                    app.Aux1EditField.Value = 10;
                    app.Aux2EditField.Value = 10;
                case 'Alkanes'
                    app.TEEditField.Value = 0;
                    app.ATPEditField.Value = 1000;
                    app.Aux1EditField.Value = 94.56;
                    app.Aux2EditField.Value = 10;
                case 'Methyl Ketones'
                    app.TEEditField.Value = 10;
                    app.ATPEditField.Value = 1000;
                    app.TEDropDown.Value = 'BTE';
                    app.Aux1EditField.Value = 170;
                    app.Aux2EditField.Value = 2600;
                    app.Aux3EditField.Value = 10;
                    app.Aux4EditField.Value = 10;
                    app.Aux5EditField.Value = 10;
                    app.Aux6EditField.Value = 10;
                    app.EnzDrop1.Value = 'ecFadD';
                    app.EnzDrop2.Value = 'ACO';
                    app.EnzDrop3.Value = 'PpFadB';
                    app.EnzDrop4.Value = 'PsFadM';
                case 'Methyl Ketones - Thiolase'
                    app.TEEditField.Value = 10;
                    app.ATPEditField.Value = 1000;
                    app.TEDropDown.Value = 'CpFatB';
                    app.Aux1EditField.Value = 170;
                    app.Aux2EditField.Value = 2600;
                    app.Aux3EditField.Value = 10;
                    app.Aux4EditField.Value = 10;
                    app.Aux5EditField.Value = 10;
                    app.EnzDrop1.Value = 'FadD6';
                    app.EnzDrop2.Value = 'ReBktB';
                    app.EnzDrop3.Value = 'PsFadM';
                case 'FAMEs'
                    app.TEEditField.Value = 10;
                    app.ATPEditField.Value = 1000;
                    app.TEDropDown.Value = 'BTE';
                    app.Aux1EditField.Value = 70.8;
                    app.Aux2EditField.Value = 10;
                case 'FAEEs'
                    app.TEDropDown.Value = 'TesA';
                    app.TEEditField.Value = 2.0206;
                    app.ATPEditField.Value = 1000;
                    app.Aux1EditField.Value = 10;
                    app.Aux2EditField.Value = 10;
            end 

            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: PlotTypeDropDown
        function PlotTypeDropDownValueChanged(app, event)
%             value = app.PlotTypeDropDown.Value;
            plot_type = app.PlotTypeDropDown.Value;
            ax = app.UIAxes;
           
%             cla(ax,'reset')
%             if strcmp(plot_type,'Product Profile')
%                 ax.XLabel.String = 'Chain Length';
%                 ax.YLabel.String = 'Concentration (\muM)';
%                 ax.Title.String = 'Product Profile';
%             else
%                 ax.XLabel.String = 'Time (s)';
%                 ax.YLabel.String = 'Concentration (\muM)';
%                 ax.Title.String = 'Time Course';
%             end
            app.UIAxes.Position = [14 41 392 282];  

        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            save_name = app.SaveRunAsEditField.Value;

            fas_enzymes = [0, ...
                app.FabDEditField.Value, ...
                app.FabHEditField.Value, ...
                app.FabGEditField.Value, ...
                app.FabZEditField.Value, ...
                app.FabIEditField.Value,...
                app.TEEditField.Value, ...
                app.FabFEditField.Value, ...
                app.FabAEditField.Value, ...
                app.FabBEditField.Value ...
                ];

            fas_components = [app.ATPEditField.Value, 0, ...
                app.AcetylCoAEditField.Value, ...
                app.holoACPEditField.Value, ...
                app.NADPHEditField.Value, ...
                app.NADHEditField.Value, ...
                0, app.MalonylCoAEditField.Value, ...
                0,0,0];


            model_scope = app.ModelScopeDropDown.Value;

            % collect auxiliary enzyme conc. from aux comp tab
            if strcmp(model_scope,'Fatty Acids')
                aux_enz = NaN;
            else
                aux_enz = NaN(1,6);
                for i = 1:6
                    auxstr = 'app.Aux';
                    eval(['aux_enz(i) =' auxstr num2str(i) 'EditField' '.Value;']);
                end
            end

            if strcmp(model_scope, 'Methyl Ketones')
                enz_identity = strings(1,4);
                enz_identity(1) = app.EnzDrop1.Value;
                enz_identity(2) = app.EnzDrop2.Value;
                enz_identity(3) = app.EnzDrop3.Value;
                enz_identity(4) = app.EnzDrop4.Value;
            elseif strcmp(model_scope, 'Methyl Ketones - Thiolase')
                enz_identity = strings(1,3);
                enz_identity(1) = app.EnzDrop1.Value;
                enz_identity(2) = app.EnzDrop2.Value;
                enz_identity(3) = app.EnzDrop3.Value;
            else
                enz_identity = NaN;
            end

            user = struct;
            user.model_scope = model_scope;
            user.aux_enz = aux_enz;
            user.fas_enz = fas_enzymes;
            user.fas_com = fas_components;
            user.time = [0 app.TimeSpanEditField.Value];
            user.TE = app.TEDropDown.Value;
            user.enz_identity = enz_identity;

            app.SAVEButton.Text = '...';
            app.SAVEButton.BackgroundColor = [0.06,1.00,1.00];
            pause(0.5);
            model_saver(user,save_name)

            app.SAVEButton.Text = 'SAVE';
            app.SAVEButton.BackgroundColor = [0.00,0.45,0.74];
            app.savedtext.Visible = 'on';
        end

        % Value changed function: FabDEditField
        function FabDEditFieldValueChanged(app, event)
            value = app.FabDEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: FabZEditField
        function FabZEditFieldValueChanged(app, event)
            value = app.FabZEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';

        end

        % Value changed function: AcetylCoAEditField
        function AcetylCoAEditFieldValueChanged(app, event)
            value = app.AcetylCoAEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: FabHEditField
        function FabHEditFieldValueChanged(app, event)
            value = app.FabHEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: FabFEditField
        function FabFEditFieldValueChanged(app, event)
            value = app.FabFEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: MalonylCoAEditField
        function MalonylCoAEditFieldValueChanged(app, event)
            value = app.MalonylCoAEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: FabIEditField
        function FabIEditFieldValueChanged(app, event)
            value = app.FabIEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: FabAEditField
        function FabAEditFieldValueChanged(app, event)
            value = app.FabAEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: NADPHEditField
        function NADPHEditFieldValueChanged(app, event)
            value = app.NADPHEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: FabGEditField
        function FabGEditFieldValueChanged(app, event)
            value = app.FabGEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: FabBEditField
        function FabBEditFieldValueChanged(app, event)
            value = app.FabBEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: NADHEditField
        function NADHEditFieldValueChanged(app, event)
            value = app.NADHEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: holoACPEditField
        function holoACPEditFieldValueChanged(app, event)
            value = app.holoACPEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: TEDropDown
        function TEDropDownValueChanged(app, event)
            value = app.TEDropDown.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: TEEditField
        function TEEditFieldValueChanged(app, event)
            value = app.TEEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: ATPEditField
        function ATPEditFieldValueChanged(app, event)
            value = app.ATPEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: Aux1EditField
        function Aux1EditFieldValueChanged(app, event)
            value = app.Aux1EditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: Aux2EditField
        function Aux2EditFieldValueChanged(app, event)
            value = app.Aux2EditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: Aux3EditField
        function Aux3EditFieldValueChanged(app, event)
            value = app.Aux3EditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: Aux4EditField
        function Aux4EditFieldValueChanged(app, event)
            value = app.Aux4EditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: TimeSpanEditField
        function TimeSpanEditFieldValueChanged(app, event)
            value = app.TimeSpanEditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: Aux5EditField
        function Aux5EditFieldValueChanged(app, event)
            value = app.Aux5EditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: Aux6EditField
        function Aux6EditFieldValueChanged(app, event)
            value = app.Aux6EditField.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: EnzDrop1
        function EnzDrop1ValueChanged(app, event)
            value = app.EnzDrop1.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: EnzDrop2
        function EnzDrop2ValueChanged(app, event)
            value = app.EnzDrop2.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: EnzDrop3
        function EnzDrop3ValueChanged(app, event)
            value = app.EnzDrop3.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end

        % Value changed function: EnzDrop4
        function EnzDrop4ValueChanged(app, event)
            value = app.EnzDrop4.Value;
            app.SAVEButton.Enable = false;
            app.savedtext.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Setup = uipanel(app.UIFigure);
            app.ModelScopeLabel = uilabel(app.Setup);
            app.ModelScopeLabel.Text = 'Model Scope:';
            app.ModelScopeDropDown = uidropdown(app.Setup);
            app.ModelScopeDropDown.Items = {'Fatty Acids', 'Alcohols', 'Alcohols - ACR1', 'Alcohols - ACR2', 'Alcohols - ATR', 'Alkanes', 'Methyl Ketones', 'Methyl Ketones - Thiolase', 'FAMEs', 'FAEEs'};
            app.ModelScopeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelScopeDropDownValueChanged, true);
            app.ModelScopeDropDown.Value = 'Fatty Acids';
            app.TimeSpansEditFieldLabel = uilabel(app.Setup);
            app.TimeSpansEditFieldLabel.Text = 'Time Span (s)';
            app.TimeSpanEditField = uieditfield(app.Setup, 'numeric');
            app.TimeSpanEditField.ValueChangedFcn = createCallbackFcn(app, @TimeSpanEditFieldValueChanged, true);
            app.TimeSpanEditField.Value = 720;
            app.SaveRunAsEditFieldLabel = uilabel(app.Setup);
            app.SaveRunAsEditFieldLabel.Text = 'Save Run As:';
            app.SaveRunAsEditField = uieditfield(app.Setup, 'text');
            app.matLabel = uilabel(app.Setup);
            app.matLabel.Text = '.mat';
            app.SAVEButton = uibutton(app.Setup, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Enable = 'off';
            app.SAVEButton.Text = 'SAVE';
            app.savedtext = uilabel(app.Setup);
            app.savedtext.Text = 'SAVED!';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RunModelTab = uitab(app.TabGroup);
            app.RunModelTab.Title = 'Run Model';
            app.UIAxes = uiaxes(app.RunModelTab);
            title(app.UIAxes, 'Product Profile')
            xlabel(app.UIAxes, 'Chain Length')
            ylabel(app.UIAxes, 'Concentration (uM)')
            app.RUNButton = uibutton(app.RunModelTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.PlotTypeLabel = uilabel(app.RunModelTab);
            app.PlotTypeLabel.Text = 'Plot Type:';
            app.PlotTypeDropDown = uidropdown(app.RunModelTab);
            app.PlotTypeDropDown.Items = {'Product Profile', 'Acyl-ACPs', 'Fatty Acids'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTypeDropDownValueChanged, true);
            app.PlotTypeDropDown.Value = 'Product Profile';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.InitialConcentrationsuMTab = uitab(app.TabGroup2);
            app.InitialConcentrationsuMTab.Title = 'Initial Concentrations (uM)';
            app.BaseFASPanel = uipanel(app.InitialConcentrationsuMTab);
            app.BaseFASPanel.Title = 'Base FAS';
            app.FabDEditField_2Label = uilabel(app.BaseFASPanel);
            app.FabDEditField_2Label.Text = 'FabD';
            app.FabDEditField = uieditfield(app.BaseFASPanel, 'numeric');
            app.FabDEditField.ValueChangedFcn = createCallbackFcn(app, @FabDEditFieldValueChanged, true);
            app.FabDEditField.Value = 1;
            app.FabGEditField_2Label = uilabel(app.BaseFASPanel);
            app.FabGEditField_2Label.Text = 'FabG';
            app.FabGEditField = uieditfield(app.BaseFASPanel, 'numeric');
            app.FabGEditField.ValueChangedFcn = createCallbackFcn(app, @FabGEditFieldValueChanged, true);
            app.FabGEditField.Value = 1;
            app.FabHEditFieldLabel = uilabel(app.BaseFASPanel);
            app.FabHEditFieldLabel.Text = 'FabH';
            app.FabHEditField = uieditfield(app.BaseFASPanel, 'numeric');
            app.FabHEditField.ValueChangedFcn = createCallbackFcn(app, @FabHEditFieldValueChanged, true);
            app.FabHEditField.Value = 1;
            app.FabZEditField_2Label = uilabel(app.BaseFASPanel);
            app.FabZEditField_2Label.Text = 'FabZ';
            app.FabZEditField = uieditfield(app.BaseFASPanel, 'numeric');
            app.FabZEditField.ValueChangedFcn = createCallbackFcn(app, @FabZEditFieldValueChanged, true);
            app.FabZEditField.Value = 1;
            app.FabIEditField_2Label = uilabel(app.BaseFASPanel);
            app.FabIEditField_2Label.Text = 'FabI';
            app.FabIEditField = uieditfield(app.BaseFASPanel, 'numeric');
            app.FabIEditField.ValueChangedFcn = createCallbackFcn(app, @FabIEditFieldValueChanged, true);
            app.FabIEditField.Value = 1;
            app.FabFEditFieldLabel = uilabel(app.BaseFASPanel);
            app.FabFEditFieldLabel.Text = 'FabF';
            app.FabFEditField = uieditfield(app.BaseFASPanel, 'numeric');
            app.FabFEditField.ValueChangedFcn = createCallbackFcn(app, @FabFEditFieldValueChanged, true);
            app.FabFEditField.Value = 1;
            app.FabAEditField_2Label = uilabel(app.BaseFASPanel);
            app.FabAEditField_2Label.Text = 'FabA';
            app.FabAEditField = uieditfield(app.BaseFASPanel, 'numeric');
            app.FabAEditField.ValueChangedFcn = createCallbackFcn(app, @FabAEditFieldValueChanged, true);
            app.FabAEditField.Value = 1;
            app.FabBEditField_6Label = uilabel(app.BaseFASPanel);
            app.FabBEditField_6Label.Text = 'FabB';
            app.FabBEditField = uieditfield(app.BaseFASPanel, 'numeric');
            app.FabBEditField.ValueChangedFcn = createCallbackFcn(app, @FabBEditFieldValueChanged, true);
            app.FabBEditField.Value = 1;
            app.AcetylCoAEditField_2Label = uilabel(app.BaseFASPanel);
            app.AcetylCoAEditField_2Label.Text = 'Acetyl-CoA';
            app.AcetylCoAEditField = uieditfield(app.BaseFASPanel, 'numeric');
            app.AcetylCoAEditField.ValueChangedFcn = createCallbackFcn(app, @AcetylCoAEditFieldValueChanged, true);
            app.AcetylCoAEditField.Value = 500;
            app.MalonylCoAEditField_2Label = uilabel(app.BaseFASPanel);
            app.MalonylCoAEditField_2Label.Text = 'Malonyl-CoA';
            app.MalonylCoAEditField = uieditfield(app.BaseFASPanel, 'numeric');
            app.MalonylCoAEditField.ValueChangedFcn = createCallbackFcn(app, @MalonylCoAEditFieldValueChanged, true);
            app.MalonylCoAEditField.Value = 500;
            app.holoACPEditField_2Label = uilabel(app.BaseFASPanel);
            app.holoACPEditField_2Label.Text = 'holo-ACP';
            app.holoACPEditField = uieditfield(app.BaseFASPanel, 'numeric');
            app.holoACPEditField.ValueChangedFcn = createCallbackFcn(app, @holoACPEditFieldValueChanged, true);
            app.holoACPEditField.Value = 10;
            app.NADPHEditField_2Label = uilabel(app.BaseFASPanel);
            app.NADPHEditField_2Label.Text = 'NADPH';
            app.NADPHEditField = uieditfield(app.BaseFASPanel, 'numeric');
            app.NADPHEditField.ValueChangedFcn = createCallbackFcn(app, @NADPHEditFieldValueChanged, true);
            app.NADPHEditField.Value = 1000;
            app.NADHEditField_2Label = uilabel(app.BaseFASPanel);
            app.NADHEditField_2Label.Text = 'NADH';
            app.NADHEditField = uieditfield(app.BaseFASPanel, 'numeric');
            app.NADHEditField.ValueChangedFcn = createCallbackFcn(app, @NADHEditFieldValueChanged, true);
            app.NADHEditField.Value = 1000;
            app.SecondaryReset = uibutton(app.BaseFASPanel, 'push');
            app.SecondaryReset.ButtonPushedFcn = createCallbackFcn(app, @SecondaryResetPushed, true);
            app.SecondaryReset.Text = 'Reset';
            app.AuxillaryComponentsPanel = uipanel(app.InitialConcentrationsuMTab);
            app.AuxillaryComponentsPanel.Title = 'Auxillary Components';
            app.TEEditFieldLabel = uilabel(app.AuxillaryComponentsPanel);
            app.TEEditFieldLabel.Text = 'TE';
            app.TEEditField = uieditfield(app.AuxillaryComponentsPanel, 'numeric');
            app.TEEditField.ValueChangedFcn = createCallbackFcn(app, @TEEditFieldValueChanged, true);
            app.TEEditField.Value = 10;
            app.TEDropDownLabel = uilabel(app.AuxillaryComponentsPanel);
            app.TEDropDownLabel.Text = 'TE';
            app.TEDropDown = uidropdown(app.AuxillaryComponentsPanel);
            app.TEDropDown.Items = {'TesA', 'CpFatB', 'BTE'};
            app.TEDropDown.ValueChangedFcn = createCallbackFcn(app, @TEDropDownValueChanged, true);
            app.TEDropDown.Value = 'TesA';
            app.ATPEditFieldLabel = uilabel(app.AuxillaryComponentsPanel);
            app.ATPEditFieldLabel.Text = 'ATP';
            app.ATPEditField = uieditfield(app.AuxillaryComponentsPanel, 'numeric');
            app.ATPEditField.ValueChangedFcn = createCallbackFcn(app, @ATPEditFieldValueChanged, true);
            app.ATPEditField.Value = 1000;
            app.Aux1EditFieldLabel = uilabel(app.AuxillaryComponentsPanel);
            app.Aux1EditFieldLabel.Text = 'Aux1';
            app.Aux1EditField = uieditfield(app.AuxillaryComponentsPanel, 'numeric');
            app.Aux1EditField.ValueChangedFcn = createCallbackFcn(app, @Aux1EditFieldValueChanged, true);
            app.Aux1EditField.Value = 10;
            app.Aux2EditFieldLabel = uilabel(app.AuxillaryComponentsPanel);
            app.Aux2EditFieldLabel.Text = 'Aux2';
            app.Aux2EditField = uieditfield(app.AuxillaryComponentsPanel, 'numeric');
            app.Aux2EditField.ValueChangedFcn = createCallbackFcn(app, @Aux2EditFieldValueChanged, true);
            app.Aux2EditField.Value = 10;
            app.Aux3EditFieldLabel = uilabel(app.AuxillaryComponentsPanel);
            app.Aux3EditFieldLabel.Text = 'Aux3';
            app.Aux3EditField = uieditfield(app.AuxillaryComponentsPanel, 'numeric');
            app.Aux3EditField.ValueChangedFcn = createCallbackFcn(app, @Aux3EditFieldValueChanged, true);
            app.Aux3EditField.Value = 10;
            app.Aux4EditFieldLabel = uilabel(app.AuxillaryComponentsPanel);
            app.Aux4EditFieldLabel.Text = 'Aux4';
            app.Aux4EditField = uieditfield(app.AuxillaryComponentsPanel, 'numeric');
            app.Aux4EditField.ValueChangedFcn = createCallbackFcn(app, @Aux4EditFieldValueChanged, true);
            app.Aux4EditField.Value = 10;
            app.PrimaryReset = uibutton(app.AuxillaryComponentsPanel, 'push');
            app.PrimaryReset.ButtonPushedFcn = createCallbackFcn(app, @PrimaryResetButtonPushed, true);
            app.PrimaryReset.Text = 'Reset';
            app.Aux5EditFieldLabel = uilabel(app.AuxillaryComponentsPanel);
            app.Aux5EditFieldLabel.Text = 'Aux5';
            app.Aux5EditField = uieditfield(app.AuxillaryComponentsPanel, 'numeric');
            app.Aux5EditField.ValueChangedFcn = createCallbackFcn(app, @Aux5EditFieldValueChanged, true);
            app.Aux5EditField.Value = 10;
            app.Aux6EditFieldLabel = uilabel(app.AuxillaryComponentsPanel);
            app.Aux6EditFieldLabel.Text = 'Aux6';
            app.Aux6EditField = uieditfield(app.AuxillaryComponentsPanel, 'numeric');
            app.Aux6EditField.ValueChangedFcn = createCallbackFcn(app, @Aux6EditFieldValueChanged, true);
            app.Aux6EditField.Value = 10;
            app.EnzDrop1 = uidropdown(app.AuxillaryComponentsPanel);
            app.EnzDrop1.Items = {'ecFadD', 'FadD6'};
            app.EnzDrop1.ValueChangedFcn = createCallbackFcn(app, @EnzDrop1ValueChanged, true);
            app.EnzDrop1.Value = 'ecFadD';
            app.EnzDrop2 = uidropdown(app.AuxillaryComponentsPanel);
            app.EnzDrop2.Items = {'ecFadE', 'ACO'};
            app.EnzDrop2.ValueChangedFcn = createCallbackFcn(app, @EnzDrop2ValueChanged, true);
            app.EnzDrop2.Value = 'ecFadE';
            app.EnzDrop3 = uidropdown(app.AuxillaryComponentsPanel);
            app.EnzDrop3.Items = {'ecFadB', 'PpFadB'};
            app.EnzDrop3.ValueChangedFcn = createCallbackFcn(app, @EnzDrop3ValueChanged, true);
            app.EnzDrop3.Value = 'ecFadB';
            app.EnzDrop4 = uidropdown(app.AuxillaryComponentsPanel);
            app.EnzDrop4.Items = {'ecFadM', 'PsFadM'};
            app.EnzDrop4.ValueChangedFcn = createCallbackFcn(app, @EnzDrop4ValueChanged, true);
            app.EnzDrop4.Value = 'ecFadM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fas_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end